classdef gaussSeidelView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        CalcularButton          matlab.ui.control.Button
        GaussSeidelLabel        matlab.ui.control.Label
        CondicindeparadaPanel   matlab.ui.container.Panel
        amountV                 matlab.ui.control.NumericEditField
        CantidadEditFieldLabel  matlab.ui.control.Label
        stopType                matlab.ui.control.DropDown
        TipoDropDownLabel       matlab.ui.control.Label
        IngreselosdatosPanel    matlab.ui.container.Panel
        MatrizordenadaLabel     matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        EntradasB               matlab.ui.control.Table
        EntradasA               matlab.ui.control.Table
        ReducirlaMatrizButton   matlab.ui.control.Button
        ExpandirlaMatrizButton  matlab.ui.control.Button
        Panel_2                 matlab.ui.container.Panel
        ResultadoLabel          matlab.ui.control.Label
        ErrorNormalizadoLabel   matlab.ui.control.Label
        TablaResultados         matlab.ui.control.Table
        TablaErrorNormalizados  matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EntradasA.Data = zeros(3, 3);
            app.EntradasB.Data = zeros(3, 1);
            app.TablaResultados.Data = zeros(3, 1);
        end

        % Button pushed function: ExpandirlaMatrizButton
        function ExpandirlaMatrizButtonPushed(app, event)
            
            % Expandir el tamaño de las entradas principales
            app.EntradasA.Data(end + 1, :) = zeros(1, width(app.EntradasA.Data));
            app.EntradasA.Data(:, end + 1) = zeros(1, height(app.EntradasA.Data));

            % Organizar los nombres de las columnas.
            aColumnas = ["a1", "a2", "a3"];
            for n = 4:width(app.EntradasA.Data)
                aColumnas(end + 1) = "a" + n;
            end
            app.EntradasA.ColumnName = aColumnas;
               
            % Agregar nueva fila a las tablas que no cambian de forma
            % rápida
            app.EntradasB.Data(end + 1, :) = zeros(1, width(app.EntradasB.Data));
            app.TablaResultados.Data(end + 1, :) = zeros(1, width(app.TablaResultados.Data));

            app.ReducirlaMatrizButton.Enable = true;
       

        end

        % Button pushed function: ReducirlaMatrizButton
        function ReducirlaMatrizButtonPushed(app, event)

            app.EntradasA.Data = app.EntradasA.Data(1:end - 1, 1:end - 1);
            app.TablaResultados.Data = app.TablaResultados.Data(1:end - 1, :);
            app.EntradasB.Data = app.EntradasB.Data(1:end - 1, :);

            % Minímo deben existir un ingreso de 3 valores.
            if (height(app.TablaResultados.Data) <= 3)
                app.ReducirlaMatrizButton.Enable = false;
            end

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           
            a = app.EntradasA.Data;
            b = app.EntradasB.Data;
            amount = app.amountV.Value;
            x0 = transpose(app.TablaResultados.Data(:, 1));

            % Colocar el resultado de la ejecución
            type = app.stopType.Value;
            try
                if strcmp(type,"Error") == 0
                    resultado = gauss_seidel(a, b, x0, -1, amount);
                else
                    resultado = gauss_seidel(a, b, x0, amount, -1);
                end
            catch ex
                errordlg(ex.message, 'Error de Ejecución');
                return
            end
            app.TablaResultados.Data = resultado;

            % Colocar nombre a las columnas
            columnas = "x0";
            for n = 1:width(app.TablaResultados.Data) - 1
                columnas(end + 1) = "x" + n;
            end
            app.TablaResultados.ColumnName = columnas;

            app.TablaErrorNormalizados.Data = gaussErrorNormalizado(app.TablaResultados.Data);
            columnas = "x2";
            for n = 3:width(app.TablaResultados.Data)
                columnas(end + 1) = "x" + n;
            end
            app.TablaErrorNormalizados.ColumnName = columnas;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.TablaErrorNormalizados = uitable(app.Panel_2);
            app.TablaErrorNormalizados.ColumnName = '';
            app.TablaErrorNormalizados.RowName = {};
            app.TablaResultados = uitable(app.Panel_2);
            app.TablaResultados.ColumnName = {'x0'};
            app.TablaResultados.RowName = {};
            app.TablaResultados.ColumnEditable = [true false];
            app.ErrorNormalizadoLabel = uilabel(app.Panel_2);
            app.ErrorNormalizadoLabel.Text = 'Error Normalizado';
            app.ResultadoLabel = uilabel(app.Panel_2);
            app.ResultadoLabel.Text = 'Resultado';
            app.Panel = uipanel(app.UIFigure);
            app.IngreselosdatosPanel = uipanel(app.Panel);
            app.IngreselosdatosPanel.Title = 'Ingrese los datos';
            app.ExpandirlaMatrizButton = uibutton(app.IngreselosdatosPanel, 'push');
            app.ExpandirlaMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandirlaMatrizButtonPushed, true);
            app.ExpandirlaMatrizButton.Text = 'Expandir la Matriz';
            app.ReducirlaMatrizButton = uibutton(app.IngreselosdatosPanel, 'push');
            app.ReducirlaMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @ReducirlaMatrizButtonPushed, true);
            app.ReducirlaMatrizButton.Enable = 'off';
            app.ReducirlaMatrizButton.Text = 'Reducir la Matriz';
            app.EntradasA = uitable(app.IngreselosdatosPanel);
            app.EntradasA.ColumnName = {'a1'; 'a2'; 'a3'};
            app.EntradasA.RowName = {};
            app.EntradasA.ColumnEditable = true;
            app.EntradasB = uitable(app.IngreselosdatosPanel);
            app.EntradasB.ColumnName = {'b'};
            app.EntradasB.RowName = {};
            app.EntradasB.ColumnEditable = true;
            app.Label = uilabel(app.IngreselosdatosPanel);
            app.Label.Text = '=';
            app.MatrizordenadaLabel = uilabel(app.IngreselosdatosPanel);
            app.MatrizordenadaLabel.Text = 'Matriz ordenada';
            app.CondicindeparadaPanel = uipanel(app.Panel);
            app.CondicindeparadaPanel.Title = 'Condición de parada';
            app.TipoDropDownLabel = uilabel(app.CondicindeparadaPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.stopType = uidropdown(app.CondicindeparadaPanel);
            app.stopType.Items = {'Error', 'Iteración'};
            app.stopType.Placeholder = 'Seleccione...';
            app.stopType.Value = 'Iteración';
            app.CantidadEditFieldLabel = uilabel(app.CondicindeparadaPanel);
            app.CantidadEditFieldLabel.Text = 'Cantidad';
            app.amountV = uieditfield(app.CondicindeparadaPanel, 'numeric');
            app.GaussSeidelLabel = uilabel(app.Panel);
            app.GaussSeidelLabel.Text = 'Gauss Seidel';
            app.CalcularButton = uibutton(app.Panel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gaussSeidelView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end