classdef jacobiView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EntradasLabel           matlab.ui.control.Label
        ErrorNormalizadoLabel   matlab.ui.control.Label
        TablaErrorNormalizados  matlab.ui.control.Table
        CalcularButton          matlab.ui.control.Button
        ReducirlaMatrizButton   matlab.ui.control.Button
        ExpandirlaMatrizButton  matlab.ui.control.Button
        EntradasA               matlab.ui.control.Table
        ResultadosLabel         matlab.ui.control.Label
        EntradasB               matlab.ui.control.Table
        TablaResultados         matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EntradasA.Data = zeros(3, 3);
            app.EntradasB.Data = zeros(3, 1);
            app.TablaResultados.Data = zeros(3, 1);
        end

        % Button pushed function: ExpandirlaMatrizButton
        function ExpandirlaMatrizButtonPushed(app, event)
            
            % Expandir el tamaño de las entradas principales
            app.EntradasA.Data(end + 1, :) = zeros(1, width(app.EntradasA.Data));
            app.EntradasA.Data(:, end + 1) = zeros(1, height(app.EntradasA.Data));

            % Organizar los nombres de las columnas.
            aColumnas = ["a1", "a2", "a3"];
            for n = 4:width(app.EntradasA.Data)
                aColumnas(end + 1) = "a" + n;
            end
            app.EntradasA.ColumnName = aColumnas;
               
            % Agregar nueva fila a las tablas que no cambian de forma
            % rápida
            app.EntradasB.Data(end + 1, :) = zeros(1, width(app.EntradasB.Data));
            app.TablaResultados.Data(end + 1, :) = zeros(1, width(app.TablaResultados.Data));

            app.ReducirlaMatrizButton.Enable = true;
       

        end

        % Button pushed function: ReducirlaMatrizButton
        function ReducirlaMatrizButtonPushed(app, event)

            app.EntradasA.Data = app.EntradasA.Data(1:end - 1, 1:end - 1);
            app.TablaResultados.Data = app.TablaResultados.Data(1:end - 1, :);
            app.EntradasB.Data = app.EntradasB.Data(1:end - 1, :);

            % Minímo deben existir un ingreso de 3 valores.
            if (height(app.TablaResultados.Data) <= 3)
                app.ReducirlaMatrizButton.Enable = false;
            end

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           
            a = app.EntradasA.Data;
            b = app.EntradasB.Data;
            x0 = transpose(app.TablaResultados.Data(:, 1));
            parErrorNormalizado = 0.001;

            % Colocar el resultado de la ejecución
            app.TablaResultados.Data = jacobi(a, b, x0, 1000, parErrorNormalizado);

            % Colocar nombre a las columnas
            columnas = "x0";
            for n = 1:width(app.TablaResultados.Data) - 1
                columnas(end + 1) = "x" + n;
            end
            app.TablaResultados.ColumnName = columnas;

            app.TablaErrorNormalizados.Data = jacobiErrorNormalizado(app.TablaResultados.Data);
            columnas = "x2";
            for n = 3:width(app.TablaResultados.Data)
                columnas(end + 1) = "x" + n;
            end
            app.TablaErrorNormalizados.ColumnName = columnas;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TablaResultados = uitable(app.UIFigure);
            app.TablaResultados.ColumnName = {'x0'};
            app.TablaResultados.RowName = {};
            app.TablaResultados.ColumnEditable = [true false];
            app.EntradasB = uitable(app.UIFigure);
            app.EntradasB.ColumnName = {'b'};
            app.EntradasB.RowName = {};
            app.EntradasB.ColumnEditable = true;
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.EntradasA = uitable(app.UIFigure);
            app.EntradasA.ColumnName = {'a1'; 'a2'; 'a3'};
            app.EntradasA.RowName = {};
            app.EntradasA.ColumnEditable = true;
            app.ExpandirlaMatrizButton = uibutton(app.UIFigure, 'push');
            app.ExpandirlaMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandirlaMatrizButtonPushed, true);
            app.ExpandirlaMatrizButton.Text = 'Expandir la Matriz';
            app.ReducirlaMatrizButton = uibutton(app.UIFigure, 'push');
            app.ReducirlaMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @ReducirlaMatrizButtonPushed, true);
            app.ReducirlaMatrizButton.Enable = 'off';
            app.ReducirlaMatrizButton.Text = 'Reducir la Matriz';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.TablaErrorNormalizados = uitable(app.UIFigure);
            app.TablaErrorNormalizados.ColumnName = '';
            app.TablaErrorNormalizados.RowName = {};
            app.ErrorNormalizadoLabel = uilabel(app.UIFigure);
            app.ErrorNormalizadoLabel.Text = 'Error Normalizado';
            app.EntradasLabel = uilabel(app.UIFigure);
            app.EntradasLabel.Text = 'Entradas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = jacobiView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end