classdef mainView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        AutoresPanel         matlab.ui.container.Panel
        CristianGuevaraBrandonOrozcoJuanPabloMahechaLabel  matlab.ui.control.Label
        Continuar            matlab.ui.control.Button
        ProyectofinalLabel   matlab.ui.control.Label
        Seleccione           matlab.ui.container.Panel
        Calculo              matlab.ui.control.DropDown
        ClculoDropDownLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Continuar
        function ContinuarButtonPushed(app, event)
            type = app.Calculo.Value;

            switch type
                case "Biseccion"
                    biseccionView
                
                case "Falsa posicion"
                    falsaPosicionView
                 
                case "Punto fijo"
                    puntoFijoView

                case "Gauss seidel"
                    gaussSeidelView

                case "Jacobi"
                    jacobiView

                case "Interpolacion Lagrange"
                    lagrangeView

                case "Interpolacion Newton"
                    newtonView

                case "Newton raphson"
                    raphsonView

                case "Simpson 1/3"
                    simpsonTresView

                case "Simpson 3/8"
                    simpsonOctavosView

                case "Trapecio"
                    trapecioView

                case "Diferencias numericas"
                    diferenciasView

                case "Runge kutta"
                    rungeKutta3View
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Seleccione = uipanel(app.Panel);
            app.Seleccione.Title = 'Seleccione el cálculo que desea realizar';
            app.ClculoDropDownLabel = uilabel(app.Seleccione);
            app.ClculoDropDownLabel.Text = 'Cálculo';
            app.Calculo = uidropdown(app.Seleccione);
            app.Calculo.Items = {'Biseccion', 'Falsa posicion', 'Punto fijo', 'Gauss seidel', 'Jacobi', 'Interpolacion Lagrange', 'Interpolacion Newton', 'Newton raphson', 'Simpson 1/3', 'Simpson 3/8', 'Trapecio', 'Diferencias numericas', 'Runge kutta'};
            app.Calculo.Value = 'Biseccion';
            app.ProyectofinalLabel = uilabel(app.Panel);
            app.ProyectofinalLabel.Text = 'Proyecto final';
            app.Continuar = uibutton(app.Panel, 'push');
            app.Continuar.ButtonPushedFcn = createCallbackFcn(app, @ContinuarButtonPushed, true);
            app.Continuar.Text = 'Continuar';
            app.AutoresPanel = uipanel(app.Panel);
            app.AutoresPanel.Title = 'Autores';
            app.CristianGuevaraBrandonOrozcoJuanPabloMahechaLabel = uilabel(app.AutoresPanel);
            app.CristianGuevaraBrandonOrozcoJuanPabloMahechaLabel.Text = {'Cristian Guevara'; 'Brandon Orozco'; 'Juan Pablo Mahecha'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end