classdef newtonView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        CalcularButton             matlab.ui.control.Button
        InterpolacindeNewtonLabel  matlab.ui.control.Label
        IngreselosdatosPanel       matlab.ui.container.Panel
        ValorX                     matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
        EvaluarEnX                 matlab.ui.control.CheckBox
        ValoresdeXyYLabel          matlab.ui.control.Label
        EntradasX                  matlab.ui.control.Table
        ReducirButton              matlab.ui.control.Button
        ExpandirButton             matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        ResultadoFinal             matlab.ui.control.Label
        ResultadodefxLabel         matlab.ui.control.Label
        Resultados                 matlab.ui.control.Table
        ValoresdeBLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EntradasX.Data = zeros(2, 3);
            app.EntradasX.RowName = ["xi","f(xi)"];
        end

        % Button pushed function: ExpandirButton
        function ExpandirButtonPushed(app, event)
            
            % Expandir el tamaño de las entradas principales
            %app.EntradasX.Data(end + 1, :) = zeros(1, width(app.EntradasX.Data));
            app.EntradasX.Data(:, end + 1) = zeros(1, height(app.EntradasX.Data));

            % Organizar los nombres de las columnas.
            aColumnas = ["x0", "x1", "x2"];
            for n = 3:width(app.EntradasX.Data)
                aColumnas(end + 1) = "x" + n;
            end
            app.EntradasX.ColumnName = aColumnas;
              
            app.ReducirButton.Enable = true;
       

        end

        % Button pushed function: ReducirButton
        function ReducirButtonPushed(app, event)

            app.EntradasX.Data = app.EntradasX.Data(1:end, 1:end - 1);
            % Minímo deben existir un ingreso de 3 valores.
            if (length(app.EntradasX.Data) <= 3)
                app.ReducirButton.Enable = false;
            end

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           
            valores = app.EntradasX.Data;
            valoresX = valores(1, :);
            valoresY = valores(2, :);

            % Colocar el resultado de la ejecución          
            app.Resultados.Data = calcularInterpolacionNewtonB(valoresX, valoresY);

            aColumnas = ["b0", "b1", "b2"];
            for n = 3:width(app.Resultados.Data)
                aColumnas(end + 1) = "b" + n;
            end
            app.Resultados.ColumnName = aColumnas;

            % Colocar el resultado de la ejecución    
            resultado = calcularInterpolacionNewtonFuncion(valores);

            if (app.EvaluarEnX.Value)
                resultado = eval(subs(resultado, app.ValorX.Value));
            end

            app.ResultadoFinal.Text = string(resultado);
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ValoresdeBLabel = uilabel(app.Panel_2);
            app.ValoresdeBLabel.Text = 'Valores de B';
            app.Resultados = uitable(app.Panel_2);
            app.Resultados.ColumnName = '';
            app.Resultados.RowName = {};
            app.ResultadodefxLabel = uilabel(app.Panel_2);
            app.ResultadodefxLabel.Text = 'Resultado de f(x)';
            app.ResultadoFinal = uilabel(app.Panel_2);
            app.ResultadoFinal.Text = '...';
            app.Panel = uipanel(app.UIFigure);
            app.IngreselosdatosPanel = uipanel(app.Panel);
            app.IngreselosdatosPanel.Title = 'Ingrese los datos';
            app.ExpandirButton = uibutton(app.IngreselosdatosPanel, 'push');
            app.ExpandirButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandirButtonPushed, true);
            app.ExpandirButton.Text = 'Expandir';
            app.ReducirButton = uibutton(app.IngreselosdatosPanel, 'push');
            app.ReducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReducirButtonPushed, true);
            app.ReducirButton.Enable = 'off';
            app.ReducirButton.Text = 'Reducir';
            app.EntradasX = uitable(app.IngreselosdatosPanel);
            app.EntradasX.ColumnName = {'x0'; 'x1'; 'x2'};
            app.EntradasX.RowName = {};
            app.EntradasX.ColumnEditable = true;
            app.ValoresdeXyYLabel = uilabel(app.IngreselosdatosPanel);
            app.ValoresdeXyYLabel.Text = 'Valores de X y Y';
            app.EvaluarEnX = uicheckbox(app.IngreselosdatosPanel);
            app.EvaluarEnX.Text = 'Evaluar en X';
            app.xEditFieldLabel = uilabel(app.IngreselosdatosPanel);
            app.xEditFieldLabel.Text = 'x = ';
            app.ValorX = uieditfield(app.IngreselosdatosPanel, 'numeric');
            app.InterpolacindeNewtonLabel = uilabel(app.Panel);
            app.InterpolacindeNewtonLabel.Text = 'Interpolación de Newton';
            app.CalcularButton = uibutton(app.Panel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newtonView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end