classdef puntoFijoView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        calculate                      matlab.ui.control.Button
        CondicindeparadaPanel          matlab.ui.container.Panel
        amountV                        matlab.ui.control.NumericEditField
        CantidadEditFieldLabel_2       matlab.ui.control.Label
        stopType                       matlab.ui.control.DropDown
        TipoDropDownLabel              matlab.ui.control.Label
        functionBi                     matlab.ui.control.EditField
        FuncinLabel                    matlab.ui.control.Label
        PuntoFijoLabel                 matlab.ui.control.Label
        LlenelossiguientescamposPanel  matlab.ui.container.Panel
        X                              matlab.ui.control.EditField
        XLabel                         matlab.ui.control.Label
        functionG                      matlab.ui.control.EditField
        FuncinLabel_2                  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ResultadoLabel                 matlab.ui.control.Label
        ResultadoTabla                 matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculate
        function calculateButtonPushed(app, event)

            try
                f = entradaFuncion(app.functionBi.Value);
                g = entradaFuncion(app.functionG.Value);
                x = eval(app.X.Value);
                amount = app.amountV.Value;
            catch ex
                errordlg(ex.message, 'Error de Datos Ingresados');
                return
            end

            type = app.stopType.Value;

            try
                if type == "Error"
                    resultado = punto_fijo(f, g, x, -1, amount);
                else
                    resultado = punto_fijo(f, g, x, amount, -1);
                end
            catch ex
                errordlg(ex.message, 'Error de Ejecución');
                return
            end

            app.ResultadoTabla.Data = resultado;

        end

        % Value changed function: functionBi
        function functionBiValueChanged(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {403, 403};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LlenelossiguientescamposPanel = uipanel(app.LeftPanel);
            app.LlenelossiguientescamposPanel.Title = 'Llene los siguientes campos';
            app.FuncinLabel_2 = uilabel(app.LlenelossiguientescamposPanel);
            app.FuncinLabel_2.Text = 'Función g';
            app.functionG = uieditfield(app.LlenelossiguientescamposPanel, 'text');
            app.XLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.XLabel.Text = 'X';
            app.X = uieditfield(app.LlenelossiguientescamposPanel, 'text');
            app.X.Value = '0';
            app.PuntoFijoLabel = uilabel(app.LeftPanel);
            app.PuntoFijoLabel.Text = 'Punto Fijo';
            app.FuncinLabel = uilabel(app.LeftPanel);
            app.FuncinLabel.Text = 'Función f';
            app.functionBi = uieditfield(app.LeftPanel, 'text');
            app.functionBi.ValueChangedFcn = createCallbackFcn(app, @functionBiValueChanged, true);
            app.CondicindeparadaPanel = uipanel(app.LeftPanel);
            app.CondicindeparadaPanel.Title = 'Condición de parada';
            app.TipoDropDownLabel = uilabel(app.CondicindeparadaPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.stopType = uidropdown(app.CondicindeparadaPanel);
            app.stopType.Items = {'Error', 'Iteración'};
            app.stopType.Placeholder = 'Seleccione...';
            app.stopType.Value = 'Iteración';
            app.CantidadEditFieldLabel_2 = uilabel(app.CondicindeparadaPanel);
            app.CantidadEditFieldLabel_2.Text = 'Cantidad';
            app.amountV = uieditfield(app.CondicindeparadaPanel, 'numeric');
            app.calculate = uibutton(app.LeftPanel, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculate.Text = 'Calcular';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultadoTabla = uitable(app.RightPanel);
            app.ResultadoTabla.ColumnName = {'x'; 'g(x)'; 'f(x)'; 'E. N.'};
            app.ResultadoTabla.RowName = {};
            app.ResultadoLabel = uilabel(app.RightPanel);
            app.ResultadoLabel.Text = 'Resultado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = puntoFijoView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end