classdef rungeKutta3View < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        calculate                      matlab.ui.control.Button
        RungeKutta3Label               matlab.ui.control.Label
        LlenelossiguientescamposPanel  matlab.ui.container.Panel
        y0EditField                    matlab.ui.control.EditField
        y0EditFieldLabel               matlab.ui.control.Label
        x0EditField                    matlab.ui.control.EditField
        x0EditFieldLabel               matlab.ui.control.Label
        campoIteraciones               matlab.ui.control.NumericEditField
        CantidadEditFieldLabel         matlab.ui.control.Label
        functionBi                     matlab.ui.control.EditField
        FuncinLabel                    matlab.ui.control.Label
        hNumeric                       matlab.ui.control.NumericEditField
        bLabel_2                       matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ResultadoLabel                 matlab.ui.control.Label
        ResultadoTabla                 matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculate
        function calculateButtonPushed(app, event)

            try
                f = entradaFuncion(app.functionBi.Value);
                x0 = eval(app.x0EditField.Value);
                y0 = eval(app.y0EditField.Value);
                h = app.hNumeric.Value;
            catch ex
                errordlg(ex.message, 'Error de Datos Ingresados');
                return
            end
            
            resultado = runge_kutta_3(f, h, x0, y0, app.campoIteraciones.Value);

            app.ResultadoTabla.Data = resultado;

        end

        % Value changed function: functionBi
        function functionBiValueChanged(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {321, 321};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LlenelossiguientescamposPanel = uipanel(app.LeftPanel);
            app.LlenelossiguientescamposPanel.Title = 'Llene los siguientes campos';
            app.bLabel_2 = uilabel(app.LlenelossiguientescamposPanel);
            app.bLabel_2.Text = 'h';
            app.hNumeric = uieditfield(app.LlenelossiguientescamposPanel, 'numeric');
            app.FuncinLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.FuncinLabel.Text = 'Función';
            app.functionBi = uieditfield(app.LlenelossiguientescamposPanel, 'text');
            app.functionBi.ValueChangedFcn = createCallbackFcn(app, @functionBiValueChanged, true);
            app.CantidadEditFieldLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.CantidadEditFieldLabel.Text = 'Iteraciones';
            app.campoIteraciones = uieditfield(app.LlenelossiguientescamposPanel, 'numeric');
            app.campoIteraciones.Limits = [1 Inf];
            app.campoIteraciones.Value = 1;
            app.x0EditFieldLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.LlenelossiguientescamposPanel, 'text');
            app.x0EditField.Value = '0';
            app.y0EditFieldLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.LlenelossiguientescamposPanel, 'text');
            app.y0EditField.Value = '0';
            app.RungeKutta3Label = uilabel(app.LeftPanel);
            app.RungeKutta3Label.Text = 'Runge Kutta 3';
            app.calculate = uibutton(app.LeftPanel, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculate.Text = 'Calcular';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultadoTabla = uitable(app.RightPanel);
            app.ResultadoTabla.ColumnName = {'xi'; 'yi'; 'k1'; 'x de k2'; 'y de k2'; 'k2'; 'x de k3'; 'y de k3'; 'k3'; 'yi+1'};
            app.ResultadoTabla.RowName = {};
            app.ResultadoLabel = uilabel(app.RightPanel);
            app.ResultadoLabel.Text = 'Resultado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rungeKutta3View

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end