classdef simpsonOctavosView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        calculate                      matlab.ui.control.Button
        Simpson38Label                 matlab.ui.control.Label
        LlenelossiguientescamposPanel  matlab.ui.container.Panel
        bNumeric                       matlab.ui.control.EditField
        bLabel_3                       matlab.ui.control.Label
        aNumeric                       matlab.ui.control.EditField
        aLabel                         matlab.ui.control.Label
        ValorX                         matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        EvaluarEnX                     matlab.ui.control.CheckBox
        functionBi                     matlab.ui.control.EditField
        FuncinLabel                    matlab.ui.control.Label
        nNumeric                       matlab.ui.control.NumericEditField
        bLabel_2                       matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ResultadoFinal                 matlab.ui.control.Label
        ResultadoLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculate
        function calculateButtonPushed(app, event)

            try
                f = entradaFuncion(app.functionBi.Value);
                a = eval(app.aNumeric.Value);
                b = eval(app.bNumeric.Value);
                n = app.nNumeric.Value;
            catch ex
                errordlg(ex.message, 'Error de Datos Ingresados');
                return
            end
            
            % Colocar el resultado de la ejecución    
            resultado = simpsonTresOctavos(f, a, b, n);

            if (app.EvaluarEnX.Value)
                resultado = eval(subs(resultado, app.ValorX.Value));
            end

            app.ResultadoFinal.Text = string(resultado);

        end

        % Value changed function: functionBi
        function functionBiValueChanged(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {324, 324};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LlenelossiguientescamposPanel = uipanel(app.LeftPanel);
            app.LlenelossiguientescamposPanel.Title = 'Llene los siguientes campos';
            app.bLabel_2 = uilabel(app.LlenelossiguientescamposPanel);
            app.bLabel_2.Text = 'n';
            app.nNumeric = uieditfield(app.LlenelossiguientescamposPanel, 'numeric');
            app.FuncinLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.FuncinLabel.Text = 'Función';
            app.functionBi = uieditfield(app.LlenelossiguientescamposPanel, 'text');
            app.functionBi.ValueChangedFcn = createCallbackFcn(app, @functionBiValueChanged, true);
            app.EvaluarEnX = uicheckbox(app.LlenelossiguientescamposPanel);
            app.EvaluarEnX.Text = 'Evaluar en X';
            app.xEditFieldLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.xEditFieldLabel.Text = 'x = ';
            app.ValorX = uieditfield(app.LlenelossiguientescamposPanel, 'numeric');
            app.aLabel = uilabel(app.LlenelossiguientescamposPanel);
            app.aLabel.Text = 'a';
            app.aNumeric = uieditfield(app.LlenelossiguientescamposPanel, 'text');
            app.aNumeric.Value = '0';
            app.bLabel_3 = uilabel(app.LlenelossiguientescamposPanel);
            app.bLabel_3.Text = 'b';
            app.bNumeric = uieditfield(app.LlenelossiguientescamposPanel, 'text');
            app.bNumeric.Value = '0';
            app.Simpson38Label = uilabel(app.LeftPanel);
            app.Simpson38Label.Text = 'Simpson 3/8';
            app.calculate = uibutton(app.LeftPanel, 'push');
            app.calculate.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculate.Text = 'Calcular';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultadoLabel = uilabel(app.RightPanel);
            app.ResultadoLabel.Text = 'Resultado';
            app.ResultadoFinal = uilabel(app.RightPanel);
            app.ResultadoFinal.Interpreter = 'latex';
            app.ResultadoFinal.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simpsonOctavosView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end